classdef DataInladen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TextArea                       matlab.ui.control.TextArea
        Tree                           matlab.ui.container.CheckBoxTree
        GDTNode                        matlab.ui.container.TreeNode
        LoadNode                       matlab.ui.container.TreeNode
        HRNode                         matlab.ui.container.TreeNode
        V_O2Node                       matlab.ui.container.TreeNode
        V_CO2Node                      matlab.ui.container.TreeNode
        V_ENode                        matlab.ui.container.TreeNode
        BFNode                         matlab.ui.container.TreeNode
        EqO2Node                       matlab.ui.container.TreeNode
        EqCO2Node                      matlab.ui.container.TreeNode
        RERNode                        matlab.ui.container.TreeNode
        O2pulseNode                    matlab.ui.container.TreeNode
        PaCO2Node                      matlab.ui.container.TreeNode
        EDFNode                        matlab.ui.container.TreeNode
        PCO2Node                       matlab.ui.container.TreeNode
        PCO2_DCNode                    matlab.ui.container.TreeNode
        SpO2Node                       matlab.ui.container.TreeNode
        PRNode                         matlab.ui.container.TreeNode
        DataGDTinladenButton           matlab.ui.control.Button
        BestandsnaamGDTEditField       matlab.ui.control.EditField
        BestandsnaamGDTEditFieldLabel  matlab.ui.control.Label
        BestandsnaamEDFEditField       matlab.ui.control.EditField
        BestandsnaamEDFEditFieldLabel  matlab.ui.control.Label
        StartMatlabEDFfileAnalyzerButton  matlab.ui.control.Button
        DataEDFinladenButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataedf % to hold EDF data
        infoedf % to hold EDF fileinfo
        fs % Sample Rate
        data % test data of GDT file
    end
    
    methods (Access = private)
     
        
        function drawedfgraphs(app)
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')
            t=(0:app.infoedf.NumDataRecords-1)/app.fs;
       
            for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
                currentNode=app.Tree.CheckedNodes(i);
                if(isempty(currentNode.Children)) %Only draw when at bottom of tree
                    y=app.dataedf.(currentNode.Text);
                    plot(app.UIAxes,t,y,'DisplayName',currentNode.Text);             
                end           
            end

            % for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
            %     currentNode=app.Tree.CheckedNodes(i);
            %     if(isempty(currentNode.Children)) %Only draw when at bottom of tree
            %         y=app.data(i);
            %         plot(app.UIAxes,t,y,'DisplayName',currentNode.Text);             
            %     end           
            % end
            
            legend(app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function DataEDFInladenButtonValueChanged(app, event)

        end

        % Button pushed function: DataEDFinladenButton
        function DataEDFinladenButtonPushed(app, event)
            cla(app.UIAxes) % clear previous plots
            fileName = app.BestandsnaamEDFEditField.Value
            app.dataedf = edfread(fileName)
            app.infoedf = edfinfo(fileName)

            %Calculate sample rate:
            numSamples = app.infoedf.NumDataRecords;
            testDuration = app.infoedf.NumDataRecords;
            app.fs=numSamples/testDuration;

            %Show edf parameters
            textforconsole = sprintf("Patient: " + app.infoedf.Patient + "\nDate: " + app.infoedf.StartDate + "\nTime: " + app.infoedf.StartTime + "\nNumDataRecords: " + app.infoedf.NumDataRecords + "\nSample Rate: " + app.fs + " Hz")
            app.TextArea.Value = textforconsole
            
            drawedfgraphs(app)

        end

        % Callback function
        function EDFinfoButtonPushed(app, event)
            edfFileAnalyzer(app.dataedf)
        end

        % Button pushed function: StartMatlabEDFfileAnalyzerButton
        function TextAreaValueChanging(app, event)

        end

        % Value changed function: BestandsnaamEDFEditField
        function BestandsnaamEDFEditFieldValueChanged(app, event)

        end

        % Callback function: not associated with a component
        function TreeCheckedNodesChanged(app, event)
            % checkedNodes = app.Tree.CheckedNodes;
            drawedfgraphs(app);
        end

        % Button pushed function: DataGDTinladenButton
        function DataGDTinladenButtonPushed(app, event)
            time = 0:10:(15*60);

            intervals = length(time);


            start_load = 0;
            max_load = 13;
            load = [];
            for i = start_load:max_load % Generate data like 0 0 .. 0 10 10 .. 10 20 20 .. 20
                xi = repelem(i*10,6); 
                load = cat(2, load, xi);   
            end
            
            % Match time vector
            if length(load) < length(time)
                load(end+1:length(time)) = 130;
            end
            
            % define parameters
            n_values = intervals; % number of pulse rate values to generate
            min_value = 60; % minimum pulse rate value
            max_value = 150; % maximum pulse rate value
            max_step_size = 5; % maximum step size between adjacent values
            
            % generate random step sizes
            step_sizes = randi([-max_step_size, max_step_size], 1, n_values-1);
            
            % generate pulse rate values with increasing or decreasing step sizes
            pulse_rate = [min_value cumsum(step_sizes) + min_value];
            
            app.data = [time', load', pulse_rate']

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataEDFinladenButton = uibutton(app.UIFigure, 'push');
            app.DataEDFinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataEDFinladenButtonPushed, true);
            app.DataEDFinladenButton.Text = 'Data EDF inladen';
            app.StartMatlabEDFfileAnalyzerButton = uibutton(app.UIFigure, 'push');
            app.StartMatlabEDFfileAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.StartMatlabEDFfileAnalyzerButton.Text = 'Start Matlab EDFfileAnalyzer';
            app.BestandsnaamEDFEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamEDFEditFieldLabel.Text = 'Bestandsnaam EDF';
            app.BestandsnaamEDFEditField = uieditfield(app.UIFigure, 'text');
            app.BestandsnaamEDFEditField.ValueChangedFcn = createCallbackFcn(app, @BestandsnaamEDFEditFieldValueChanged, true);
            app.BestandsnaamEDFEditField.Value = 'GD capno.edf';
            app.BestandsnaamGDTEditFieldLabel = uilabel(app.UIFigure);
            app.BestandsnaamGDTEditFieldLabel.Text = 'Bestandsnaam GDT';
            app.BestandsnaamGDTEditField = uieditfield(app.UIFigure, 'text');
            app.DataGDTinladenButton = uibutton(app.UIFigure, 'push');
            app.DataGDTinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataGDTinladenButtonPushed, true);
            app.DataGDTinladenButton.Text = 'Data GDT inladen';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.GDTNode = uitreenode(app.Tree);
            app.GDTNode.Text = 'GDT';
            app.LoadNode = uitreenode(app.GDTNode);
            app.LoadNode.Text = 'Load';
            app.HRNode = uitreenode(app.GDTNode);
            app.HRNode.Text = 'HR';
            app.V_O2Node = uitreenode(app.GDTNode);
            app.V_O2Node.Text = 'V_O2';
            app.V_CO2Node = uitreenode(app.GDTNode);
            app.V_CO2Node.Text = 'V_CO2';
            app.V_ENode = uitreenode(app.GDTNode);
            app.V_ENode.Text = 'V_E';
            app.BFNode = uitreenode(app.GDTNode);
            app.BFNode.Text = 'BF';
            app.EqO2Node = uitreenode(app.GDTNode);
            app.EqO2Node.Text = 'EqO2';
            app.EqCO2Node = uitreenode(app.GDTNode);
            app.EqCO2Node.Text = 'EqCO2';
            app.RERNode = uitreenode(app.GDTNode);
            app.RERNode.Text = 'RER';
            app.O2pulseNode = uitreenode(app.GDTNode);
            app.O2pulseNode.Text = 'O2pulse';
            app.PaCO2Node = uitreenode(app.GDTNode);
            app.PaCO2Node.Text = 'PaCO2';
            app.EDFNode = uitreenode(app.Tree);
            app.EDFNode.Text = 'EDF';
            app.PCO2Node = uitreenode(app.EDFNode);
            app.PCO2Node.Text = 'PCO2';
            app.PCO2_DCNode = uitreenode(app.EDFNode);
            app.PCO2_DCNode.Text = 'PCO2_DC';
            app.SpO2Node = uitreenode(app.EDFNode);
            app.SpO2Node.Text = 'SpO2';
            app.PRNode = uitreenode(app.EDFNode);
            app.PRNode.Text = 'PR';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataInladen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end